classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        AnalysisMapsDropDown       matlab.ui.control.DropDown
        AnalysisMapsDropDownLabel  matlab.ui.control.Label
        AnalyzeRTIdataButton       matlab.ui.control.Button
        ImageNoSlider              matlab.ui.control.Slider
        ImageNoSliderLabel         matlab.ui.control.Label
        LoadLPButton               matlab.ui.control.Button
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        AcquisitionPath % Description
        LPFileName % Description
        LP % Description
        rgbMean % Description
        drop_down_value % Description
        rgbStd % Description
        rgbMedian 
        rgbMax  % Description
        rawSkewness % Description
        norm_Skewness % Description
        rgbKurtosis % Description
        normalMap % Description
        pixelSizeX = 97% Description
        Dx % Description
        pixelSizeY % Description
        Dy % Description
        rgbMin % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadLPButton
        function LoadLPButtonPushed(app, event)
                 try
                    % Open file browser dialog
                    [filename, pathname] = uigetfile('*.lp', 'Select LP File');
                    
                    % Check if user cancelled
                    if filename ~= 0
                        % Store path and filename
                        app.AcquisitionPath = pathname;
                        app.LPFileName = filename;
                        
                        % Load LP file
                        app.LP = loadLP1(fullfile(pathname, filename));

                        % Setup slider
                        app.ImageNoSlider.Limits = [1 length(app.LP.FileNames)];
                        app.ImageNoSlider.Value = 1;
                        app.ImageNoSlider.Enable = 'on';
                        
                        % Get first image from the folder
                        firstImg = imread(fullfile(pathname, app.LP.FileNames{1}));
                        
                        % Display first image
                        imshow(firstImg, 'Parent', app.UIAxes);
                        %title(app.UIAxes, ['Image: ' app.LP.FileNames{1}]);
                        
                        % Show success message
                        msg = sprintf('LP File loaded successfully:\n%s', filename);
                        uialert(app.UIFigure, msg, 'Success', 'Icon', 'success');
                    end
                catch ME
                    % Error handling
                    uialert(app.UIFigure, ME.message, 'Error', 'Icon', 'error');
                end
        end

        % Value changing function: ImageNoSlider
        function ImageNoSliderValueChanging(app, event)
                % Get current slider value
                imageIndex = round(event.Value);


                % Load and display corresponding image
                currentImage = imread(fullfile(app.AcquisitionPath, app.LP.FileNames{imageIndex}));
                imshow(currentImage, 'Parent', app.UIAxes);

                % Update label with current image name
                app.ImageNoSliderLabel.Text= sprintf('Image %d/%d: %s',imageIndex, length(app.LP.FileNames), app.LP.FileNames{imageIndex});
        end

        % Button pushed function: AnalyzeRTIdataButton
        function AnalyzeRTIdataButtonPushed(app, event)
             try                    
                    % Calculate all descriptors
                    app.rgbMean = calculateRGBMean(app.LP);
                    app.rgbStd = calculateRGBStd(app.LP);
%                     app.rgbMedian = calculateRGBMedian(app.LP);
                    app.rgbMax = calculateRGBMax(app.LP);
                    app.rgbMin = calculateRGBMin(app.LP);
%                     [app.rawSkewness, app.norm_Skewness] = calculateRGBSkewness(app.LP, app.rgbMean, app.rgbStd);
%                     app.rgbKurtosis = calculateRGBKurtosis(app.LP, app.rgbMean, app.rgbStd);
%                     app.normalMap = calculateRGBNormal(app.LP);
%                     app.Dx = calculateDirectionalDerivativeX(app.normalMap, app.pixelSizeX);
%                     app.Dy = calculateDirectionalDerivativeY(app.normalMap, app.pixelSizeY);


                    % Display RGB Mean image
                    imshow(app.rgbMean, 'Parent', app.UIAxes2);
                    title(app.UIAxes2, 'RGB Mean Image');
                    
                    % Show success message
                    msg = sprintf('Feature Map calculated successfully');
                    uialert(app.UIFigure, msg, 'Success', 'Icon', 'success');

            catch ME
                % Error handling
                uialert(app.UIFigure, ME.message, 'Error', 'Icon', 'error');
            end
        end

        % Value changed function: AnalysisMapsDropDown
        function AnalysisMapsDropDownValueChanged(app, event)
            app.drop_down_value = app.AnalysisMapsDropDown.Value;
            switch app.drop_down_value
                case 'Mean'
                    % Display RGB Mean image
                    imshow(app.rgbMean, 'Parent', app.UIAxes2);
                    title(app.UIAxes2, 'Mean Image');
                case 'Median'
                    imshow(app.rgbMedian, 'Parent', app.UIAxes2);
                    title(app.UIAxes2, 'Median Image');
                case 'StDev'
                    imshow(app.rgbStd, 'Parent', app.UIAxes2);
                    title(app.UIAxes2, 'StDev Image');
                case 'Max'
                    imshow(app.rgbMax, 'Parent', app.UIAxes2);
                    title(app.UIAxes2, 'Max Image');
                case 'Min'
                    imshow(app.rgbMin, 'Parent', app.UIAxes2);
                    title(app.UIAxes2, 'Min Image');
                case 'Skewness'
                    imshow(app.rawSkewness, 'Parent', app.UIAxes2);
                    title(app.UIAxes2, 'Skewness Image');
                    colormap('gray');
                    clim([min(app.rawSkewness(:)) max(app.rawSkewness(:))]);  % Non-symmetric
                    colorbar;
                case 'Kurtosis'
                    imshow(app.rgbKurtosis, 'Parent', app.UIAxes2);
                    title(app.UIAxes2, 'Skewness Image');
                case 'Normal Map'
                    imshow(app.normalMap, 'Parent', app.UIAxes2);
                    title(app.UIAxes2, 'Skewness Image');
                case 'Dx'
                    imshow(app.Dx, 'Parent', app.UIAxes2);
                    title(app.UIAxes2, 'Skewness Image');
                case 'Dy'
                    imshow(app.Dy, 'Parent', app.UIAxes2);
                    title(app.UIAxes2, 'Skewness Image');
                case 'Kxx'
                    imshow(app.rgbMin, 'Parent', app.UIAxes2);
                    title(app.UIAxes2, 'Skewness Image');
                case 'Kyy'
                    imshow(app.rgbMin, 'Parent', app.UIAxes2);
                    title(app.UIAxes2, 'Skewness Image');
                case 'Kxy'
                    imshow(app.rgbMin, 'Parent', app.UIAxes2);
                    title(app.UIAxes2, 'Skewness Image');
                case 'Kmin'
                    imshow(app.rgbMin, 'Parent', app.UIAxes2);
                    title(app.UIAxes2, 'Skewness Image');
                case 'K mean'
                    imshow(app.rgbMin, 'Parent', app.UIAxes2);
                    title(app.UIAxes2, 'Skewness Image');
                case 'K gaussian'
                    imshow(app.rgbMin, 'Parent', app.UIAxes2);
                    title(app.UIAxes2, 'Skewness Image');
                case 'K melhum'
                    imshow(app.rgbMin, 'Parent', app.UIAxes2);
                    title(app.UIAxes2, 'Skewness Image');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'RTI Dataset')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Visualization of Feature Maps')
            zlabel(app.UIAxes2, 'Z')
            app.LoadLPButton = uibutton(app.UIFigure, 'push');
            app.LoadLPButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLPButtonPushed, true);
            app.LoadLPButton.Text = 'Load LP';
            app.ImageNoSliderLabel = uilabel(app.UIFigure);
            app.ImageNoSliderLabel.Text = 'Image No';
            app.ImageNoSlider = uislider(app.UIFigure);
            app.ImageNoSlider.ValueChangingFcn = createCallbackFcn(app, @ImageNoSliderValueChanging, true);
            app.AnalyzeRTIdataButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeRTIdataButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeRTIdataButtonPushed, true);
            app.AnalyzeRTIdataButton.Text = 'Analyze RTI data';
            app.AnalysisMapsDropDownLabel = uilabel(app.UIFigure);
            app.AnalysisMapsDropDownLabel.Text = 'Analysis Maps';
            app.AnalysisMapsDropDown = uidropdown(app.UIFigure);
            app.AnalysisMapsDropDown.Items = {'Mean', 'Median', 'StDev', 'Max', 'Min', 'Normal Map', 'Dx', 'Dy', 'Kxx', 'Kyy', 'Kxy', 'Kmin', 'Kmax', 'K mean', 'K gaussian', 'K melhum'};
            app.AnalysisMapsDropDown.ValueChangedFcn = createCallbackFcn(app, @AnalysisMapsDropDownValueChanged, true);
            app.AnalysisMapsDropDown.Value = 'Mean';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end